classdef identifikasi_gambar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        textBrowse                      matlab.ui.control.TextArea
        ImageSebelum                    matlab.ui.control.Image
        BukaGambarButton_2              matlab.ui.control.StateButton
        PROSESButton                    matlab.ui.control.Button
        TAMPILANLabel                   matlab.ui.control.Label
        TextHasil                       matlab.ui.control.TextArea
        TextKecepatan                   matlab.ui.control.TextArea
        TextTanggal                     matlab.ui.control.TextArea
        TextJam                         matlab.ui.control.TextArea
        HASILTEMPLATEMATCHINGLabel      matlab.ui.control.Label
        KECEPATANPEMBACAANSECONDSLabel  matlab.ui.control.Label
        WAKTUTANGGALLabel               matlab.ui.control.Label
        WAKTUJAMLabel                   matlab.ui.control.Label
        HASILDETEKSILabel               matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        PROSESLabel                     matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        IDENTIFIKASIPLATNOMORLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BukaGambarButton_2
        function BukaGambarButton_2ValueChanged(app, event)
            clc
            close all;
            clear;
            load imgfildata;
            tStart = tic;

            [file,path]=uigetfile({'*.jpg;*.bmp;*.png;*.tif'},'Choose an image');
            s=[path,file];
            figure
            picture=imread(s);
            [~,cc]=size(picture);
            picture=imresize(picture,[300 500]);
            
            if size(picture,3)==3
              picture=rgb2gray(picture);
            end
            % se=strel('rectangle',[5,5]);
            % a=imerode(picture,se);
            % figure,imshow(a);
            % b=imdilate(a,se);
            %--
            threshold = graythresh(picture);
            picture =~im2bw(picture,threshold);
            picture = bwareaopen(picture,30);
            imshow(picture)
            if cc>2000
                picture1=bwareaopen(picture,3500);
            else
                picture1=bwareaopen(picture,3000);
            end
            figure,imshow(picture1)
            picture2=picture-picture1;
            figure,imshow(picture2)
            picture2=bwareaopen(picture2,200);
            figure,imshow(picture2)
            
            [L,Ne]=bwlabel(picture2);
            propied=regionprops(L,'BoundingBox');
            hold on
            pause(1)
            for n=1:size(propied,1)
              rectangle('Position',propied(n).BoundingBox,'EdgeColor','g','LineWidth',2)
            end
            hold off
            
            figure
            final_output=[];
            t=[];
            for n=1:Ne
              [r,c] = find(L==n);
              n1=picture(min(r):max(r),min(c):max(c));
              n1=imresize(n1,[42,24]);
              imshow(n1)
              pause(0.2)
              x=[ ];
            
            totalLetters=size(imgfile,2);
            
             for k=1:totalLetters
                
                y=corr2(imgfile{1,k},n1);
                x=[x y];
                
             end
             t=[t max(x)];
             if max(x)>.40
             z=find(x==max(x));
             out=cell2mat(imgfile(2,z));
            
             final_output=[final_output out];
             end
            end

            %file = fopen('number_Plate.txt', 'wt');
                %fprintf(file,'%s\n',final_output);
                %fclose(file);                     
                %winopen('number_Plate.txt')
            tEnd = toc(tStart);
            app = identifikasi_gambar;
            app.TextHasil.Value = final_output;
            app.TextTanggal.Value = string(datetime('now', 'Format','MM/dd/uuuu'));
            app.TextJam.Value = string(datetime('now', 'Format', 'HH:mm:ss'));
            app.TextKecepatan.Value = string(tEnd);

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app = index;
        end

        % Button pushed function: PROSESButton
        function PROSESButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IDENTIFIKASIPLATNOMORLabel = uilabel(app.UIFigure);
            app.IDENTIFIKASIPLATNOMORLabel.Text = 'IDENTIFIKASI PLAT NOMOR';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.PROSESLabel = uilabel(app.UIFigure);
            app.PROSESLabel.Text = 'PROSES';
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.HASILDETEKSILabel = uilabel(app.UIFigure);
            app.HASILDETEKSILabel.Text = 'HASIL DETEKSI';
            app.WAKTUJAMLabel = uilabel(app.UIFigure);
            app.WAKTUJAMLabel.Text = 'WAKTU (JAM)';
            app.WAKTUTANGGALLabel = uilabel(app.UIFigure);
            app.WAKTUTANGGALLabel.Text = 'WAKTU (TANGGAL)';
            app.KECEPATANPEMBACAANSECONDSLabel = uilabel(app.UIFigure);
            app.KECEPATANPEMBACAANSECONDSLabel.Text = {'KECEPATAN PEMBACAAN'; '(SECONDS)'};
            app.HASILTEMPLATEMATCHINGLabel = uilabel(app.UIFigure);
            app.HASILTEMPLATEMATCHINGLabel.Text = 'HASIL TEMPLATE MATCHING';
            app.TextJam = uitextarea(app.UIFigure);
            app.TextTanggal = uitextarea(app.UIFigure);
            app.TextKecepatan = uitextarea(app.UIFigure);
            app.TextHasil = uitextarea(app.UIFigure);
            app.TAMPILANLabel = uilabel(app.UIFigure);
            app.TAMPILANLabel.Text = 'TAMPILAN';
            app.PROSESButton = uibutton(app.UIFigure, 'push');
            app.PROSESButton.ButtonPushedFcn = createCallbackFcn(app, @PROSESButtonPushed, true);
            app.PROSESButton.Text = 'PROSES';
            app.BukaGambarButton_2 = uibutton(app.UIFigure, 'state');
            app.BukaGambarButton_2.ValueChangedFcn = createCallbackFcn(app, @BukaGambarButton_2ValueChanged, true);
            app.BukaGambarButton_2.Text = 'Buka Gambar';
            app.ImageSebelum = uiimage(app.UIFigure);
            app.ImageSebelum.ImageSource = fullfile(pathToMLAPP, 'image', 'kosong.jpg');
            app.textBrowse = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image', 'home.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identifikasi_gambar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end