classdef menu_analisis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image_3               matlab.ui.control.Image
        ImageSebelum          matlab.ui.control.Image
        ImageSesudah          matlab.ui.control.Image
        PilihDropDown         matlab.ui.control.DropDown
        PilihDropDownLabel    matlab.ui.control.Label
        SesudahDiprosesLabel  matlab.ui.control.Label
        SebelumDiprosesLabel  matlab.ui.control.Label
        ProsesButton          matlab.ui.control.Button
        PanelImageProcessing  matlab.ui.container.Panel
        Image_2               matlab.ui.control.Image
        clearButton           matlab.ui.control.Button
        BukaKameraButton      matlab.ui.control.Button
        PilihGambarButton     matlab.ui.control.Button
        textBrowse            matlab.ui.control.TextArea
        PROSESPENGOLAHANIMAGEPROCESSINGLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        function results = func(app)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: PilihDropDown
        function PilihDropDownOpening(app, event)
            
        end

        % Button pushed function: ProsesButton
        function ProsesButtonPushed(app, event)
            dd = app.PilihDropDown.Value;
            imgSebelum = app.ImageSebelum.ImageSource;
            imgSesudah = imgSebelum;
            if dd == "Grayscale" 
                RGB = imread(imgSebelum);
                imgSesudah = rgb2gray(RGB);
            end
            if dd == "Thresholding"
                RGB = imread(imgSebelum);
                imgSesudah = im2bw(RGB,200/255);
                figure, imshow(imgSesudah);
            end
            if dd == "Erosi"
                t = imread (imgSebelum); 
                d = ones (3); 
                td = imdilate (t, d); 
                tde = imerode (td, d); 
                subplot (1,2,1), imshow (td) 
                title ('citra asli')
                subplot (1,2,2), imshow (tde) 
                title ('hasil erosi') 
            end
            imshow(imgSesudah);
            %app.ImageSesudah.ImageSource = imgSesudah;
        end

        % Value changed function: PilihDropDown
        function PilihDropDownValueChanged(app, event)
            %value = app.PilihDropDown.Value;
            %set(handle.Operation = value);
        end

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [file,path] = uigetfile('*.jpg');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(file)]);
               app.textBrowse.Value = fullfile(file);
               app.ImageSebelum.ImageSource = fullfile(pathToMLAPP, 'plat', file);
            end
        end

        % Value changed function: textBrowse
        function textBrowseValueChanged(app, event)
            value = app.textBrowse.Value;
            
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImageSebelum.ImageSource = fullfile(pathToMLAPP, 'image', 'kosong.jpg');
            app.ImageSesudah.ImageSource = fullfile(pathToMLAPP, 'image', 'kosong.jpg');
        end

        % Button pushed function: BukaKameraButton
        function BukaKameraButtonPushed(app, event)
            cam = webcam
            cam = webcam(devicenumber)
            cam = webcam('cameraname')
        end

        % Callback function
        function ButtonPushed(app, event)
            t = imread ('image1.jpg'); 
            d = ones (3); 
            td = imdilate (t, d); 
            tde = imerode (td, d); 
            subplot (1,2,1), imshow (td) 
            title ('citra asli')
            subplot (1,2,2), imshow (tde) 
            title ('hasil erosi') 
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            [file,path] = uigetfile('*.jpg');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(file)]);
               app.textBrowse.Value = fullfile(file);
               app.ImageSebelum.ImageSource = fullfile(pathToMLAPP, 'plat', file);
            end
        end

        % Image clicked function: Image_3
        function Image_3Clicked(app, event)
            app = index
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PROSESPENGOLAHANIMAGEPROCESSINGLabel = uilabel(app.UIFigure);
            app.PROSESPENGOLAHANIMAGEPROCESSINGLabel.Text = 'PROSES PENGOLAHAN IMAGE PROCESSING';
            app.PanelImageProcessing = uipanel(app.UIFigure);
            app.PanelImageProcessing.Title = 'Panel Image Processing';
            app.textBrowse = uitextarea(app.PanelImageProcessing);
            app.textBrowse.ValueChangedFcn = createCallbackFcn(app, @textBrowseValueChanged, true);
            app.PilihGambarButton = uibutton(app.PanelImageProcessing, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.BukaKameraButton = uibutton(app.PanelImageProcessing, 'push');
            app.BukaKameraButton.ButtonPushedFcn = createCallbackFcn(app, @BukaKameraButtonPushed, true);
            app.BukaKameraButton.Text = 'Buka Kamera';
            app.clearButton = uibutton(app.PanelImageProcessing, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.Image_2 = uiimage(app.PanelImageProcessing);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'image', 'logo_file.png');
            app.ProsesButton = uibutton(app.UIFigure, 'push');
            app.ProsesButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesButtonPushed, true);
            app.ProsesButton.Text = 'Proses';
            app.SebelumDiprosesLabel = uilabel(app.UIFigure);
            app.SebelumDiprosesLabel.Text = 'Sebelum Diproses';
            app.SesudahDiprosesLabel = uilabel(app.UIFigure);
            app.SesudahDiprosesLabel.Text = 'Sesudah Diproses';
            app.PilihDropDownLabel = uilabel(app.UIFigure);
            app.PilihDropDownLabel.Text = 'Pilih';
            app.PilihDropDown = uidropdown(app.UIFigure);
            app.PilihDropDown.Items = {'RGB', 'Grayscale', 'Thresholding', 'Erosi'};
            app.PilihDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PilihDropDownOpening, true);
            app.PilihDropDown.ValueChangedFcn = createCallbackFcn(app, @PilihDropDownValueChanged, true);
            app.PilihDropDown.Value = 'RGB';
            app.ImageSesudah = uiimage(app.UIFigure);
            app.ImageSesudah.ImageSource = fullfile(pathToMLAPP, 'image', 'kosong.jpg');
            app.ImageSebelum = uiimage(app.UIFigure);
            app.ImageSebelum.ImageSource = fullfile(pathToMLAPP, 'image', 'kosong.jpg');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageClickedFcn = createCallbackFcn(app, @Image_3Clicked, true);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'image', 'home.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = menu_analisis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end